classdef hannah2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        LoadGeometryMenu           matlab.ui.container.Menu
        rhabdomdeptSlider          matlab.ui.control.Slider
        Panel_5                    matlab.ui.container.Panel
        RhabdomdepthLabel          matlab.ui.control.Label
        rhabdomdeptSliderLabel     matlab.ui.control.Label
        ExpandFigureButton         matlab.ui.control.Button
        wavenumber_panel           matlab.ui.container.Panel
        spectrum                   matlab.ui.control.Image
        WavenumberSlider           matlab.ui.control.Slider
        WavelengthnmLabel          matlab.ui.control.Label
        btn_plt_app                matlab.ui.container.ButtonGroup
        btn_energy                 matlab.ui.control.RadioButton
        btn_abs                    matlab.ui.control.RadioButton
        btn_ima                    matlab.ui.control.RadioButton
        btn_real                   matlab.ui.control.RadioButton
        btn_plt_type               matlab.ui.container.ButtonGroup
        btn_tot                    matlab.ui.control.RadioButton
        btn_sca                    matlab.ui.control.RadioButton
        btn_inc                    matlab.ui.control.RadioButton
        btn_geom                   matlab.ui.control.RadioButton
        PlotSettingsLabel          matlab.ui.control.Label
        IndicesofRefractionLabel   matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        R1_Re                      matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        ImaginaryLabel             matlab.ui.control.Label
        RealLabel                  matlab.ui.control.Label
        RegionLabel                matlab.ui.control.Label
        SelectRegionDropDown       matlab.ui.control.DropDown
        SelectRegionDropDownLabel  matlab.ui.control.Label
        R1_Im                      matlab.ui.control.NumericEditField
        Panel_2                    matlab.ui.container.Panel
        IncidentDirectionLabel     matlab.ui.control.Label
        KnobDirection              matlab.ui.control.Knob
        Panel_4                    matlab.ui.container.Panel
        SaveButton                 matlab.ui.control.Button
        SolveButton                matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        nregions=0;
        direction = 0;
        wavenumber= 0.38;
        region_params;
        rcurr;
        chnk_array; 
        chnkrtotal;
        clmparams = [];
        hs = [];
        usr_poly;
        poly_handle;
        f_handle=[];
        f_cb=[];
        geom_class = [];
        M;
        RG;
        Fskel1 = [];
        Fskel2 = [];
        skel_struct = [];
        opts_perm = [];
        rhs=[];
        sol=[];
        targs=[];
        xxtarg = [];
        yytarg = [];
        ntarg=0;
        uscat=[];
        ugrad = [];
        uinc=[];
        uincgrad = [];
        u=[];
        sk = [];
        exp_mat = [];
        sk_targ = [];
        eva_mats = [];
        xg;
        yg;
        ngr;
        icase=6;
        targlist;
        tid=[];
        ntid=[];
        targdomain;
        default_ui_name;
        is_mat_current = false;
        xylim = [];
        plot_option = 'Geometry';
        imre = 'Real Part';
        uiax_click_t = tic();
        uiax_clicked = 0;
    end
    
    methods (Access = private)
        
        function refresh_chnk(app)
            %%%%%                
                delete(app.hs);
                [hs,chnk_arr,params] = load_geom(app.UIAxes);
                app.hs = hs;
                app.chnk_array = chnk_arr;
                app.clmparams = params;
        end
        
        function plot_geom(app,axes_in)
            if (nargin <2)
                if(~isempty(app.hs))
                    delete(app.hs);
                end
            end
            hs = [];
            if (nargin <2)
                uiax = app.UIAxes;
            else
                uiax = axes_in;
            end
            hold(uiax,'on');
            h = plot_new(uiax,app.chnk_array,'r-','LineWidth',2);
            hs = [hs,h];

            hold(uiax,'on');
            
            verts = [];
            if isfield(app.clmparams,'verts')
                verts = app.clmparams.verts;
            end
            if ~isempty(verts)
                h2 = plot(uiax,verts(1,:),verts(2,:),'k.','MarkerSize',8);
                hs = [hs,h2];
            end
            if (nargin < 2)
                app.hs = hs;
            end
            axis(uiax,app.xylim);
            drawnow
           
        end
        
        function update_rhs(app)
            dir_radians = app.direction*2*pi/360.0+pi/2;
            opts_rhs = [];
            opts_rhs.itype = 2;
            opts_rhs.alpha = dir_radians;
            app.rhs = clm.get_rhs_gui_clm_cases(app.chnk_array, ...
                app.clmparams,app.clmparams.npts,app.clmparams.alpha1,app.clmparams.alpha2,opts_rhs);
            disp('updated rhs')
            
        end
        function update_sol(app)
            app.UIFigure.Name = 'Updating solution...';
            [app.sol] = chnk.flam.solve_2by2blk(app.rhs,app.Fskel1,app.Fskel2,app.skel_struct,app.opts_perm);
%             [soltilde,it] = rcip.myGMRESR(app.M,app.RG,app.rhs, ...
%                 app.clmparams.nsys,200,eps*20);
%             app.sol = app.RG*soltilde;
%            disp(['GMRES iterations = ',num2str(it)])
            app.UIFigure.Name = app.default_ui_name;
        end
        function update_geom(app)
            app.UIFigure.Name = 'Updating geometry...';
            opts = [];
            opts.rn = app.region_params(1,:)+1j*app.region_params(2,:);
            opts.lambda = app.wavenumber;
            app.clmparams = clm.update_clmparams(app.clmparams,opts);
            app.chnk_array = clm.get_geom_clmparams(app.clmparams);
            [app.sk,~,app.exp_mat] = clm.get_compressed_postproc_im(app.chnk_array,app.clmparams);
            eps = 0.5e-5;
            opts = [];
            [app.eva_mats,app.sk_targ] = clm.get_evamat_postproc_im(app.chnk_array,app.clmparams,app.targs, ...
                app.targdomain,app.sk,eps,opts);
            
            plot_geom(app);
            app.UIFigure.Name = app.default_ui_name;
        end
        function update_matrix(app)
            app.UIFigure.Name = 'Generating matrix...';
            eps = 1e-6;
            [app.Fskel1,app.Fskel2,app.skel_struct,app.opts_perm,app.M,app.RG] = ...
            clm.get_fds_gui(app.chnk_array,app.clmparams,eps);
            
            %[app.M,app.RG] = clm.get_mat_gui(app.chnk_array, ...
            %     app.clmparams,opts);
            app.UIFigure.Name = app.default_ui_name;
        end
        
        function update_uscat(app)
            app.UIFigure.Name = 'Updating uscat...';
            eps = 1e-6;
            [app.uscat,app.ugrad] = clm.postprocess_sol_gui_fmm_fds(app.chnk_array, ...
                app.clmparams,app.targs,app.targdomain,eps, ...
                app.sol,app.sk,app.exp_mat,app.eva_mats,app.sk_targ);
            [~,m] = size(app.targs);
            app.uscat = reshape(app.uscat,[1,m]);
            app.UIFigure.Name = app.default_ui_name;
        end
        function update_uinc(app)
            [~,m] = size(app.targs);
            app.uinc = zeros(m,1);
            app.uincgrad = zeros(2,m);
            dir_radians = app.direction*2*pi/360.0+pi/2;
            idomup = find(app.clmparams.is_inf == 1);
            idomdown = find(app.clmparams.is_inf == -1);
            for i=1:app.clmparams.ndomain
                if(~isempty(app.targlist{i}))
                    if(i == idomup || i == idomdown)
                        [app.uinc(app.targlist{i}),gtmp] = ...
                clm.planewavetotal_gui(app.clmparams.k(idomup),dir_radians, ...
                app.clmparams.k(idomdown),app.targs(:,app.targlist{i}),app.clmparams.is_inf(i), ...
                idomup,idomdown,app.clmparams.coef);
                        app.uincgrad(:,app.targlist{i}) = gtmp.';
                    end
                end
                
            end
            app.uinc = reshape(app.uinc,[1,m]);
           
        end
        
        function make_plot(app,axes_in)
            
            if (nargin <2)
                uiax = app.UIAxes;
            else
                uiax = axes_in;
            end
            [~,m] = size(app.targs);
            zztarg = nan(size(app.xxtarg)) + 1i*nan(size(app.xxtarg));
            zztarg_grad = nan(2,m) + 1j*nan(2,m);
            nr_region = zeros(1,m);
            for i=1:app.clmparams.ndomain
               if(~isempty(app.targlist{i}))
                  nr_region(app.targlist{i}) = app.clmparams.rn(i);
               end
            end
            
            if (nargin < 2)
                if(~isempty(app.f_handle))
                    delete(app.f_handle);
                    delete(app.f_cb);
                end
            end
            
            disp('here3')
            disp(app.plot_option)
            if(strcmp(app.plot_option,'Geometry'))
                return
            end
            if(strcmp(app.plot_option,'Scattered Field')) 
                if ~isempty(app.uscat)
                    zztarg = app.uscat;
                    zztarg_grad = app.ugrad;
                end
            end
            if(strcmp(app.plot_option,'Incident Field'))
                if ~isempty(app.uinc)
                    idomup = find(app.clmparams.is_inf == 1);
                    idomdown = find(app.clmparams.is_inf == -1);
                    for i=1:app.clmparams.ndomain
                        if(~isempty(app.targlist{i}))
                            if(i == idomup || i == idomdown)
                                zztarg(app.targlist{i}) = app.uinc(app.targlist{i});
                                zztarg_grad(:,app.targlist{i}) = app.uincgrad(:,app.targlist{i});
                      
                            end
                        end
                
                    end
                end
            end
            
            if(strcmp(app.plot_option,'Total Field'))
                if ~isempty(app.uinc) && ~isempty(app.uscat)
                    zztarg = app.uinc + app.uscat;
                    zztarg_grad = app.uincgrad + app.ugrad;
                end
            end
            
            
            zztarg = reshape(zztarg,size(app.xxtarg));
            if (strcmp(app.imre,'Real Part'))
                zztarg_plot = real(zztarg);
            elseif (strcmp(app.imre,'Imaginary Part'))
                zztarg_plot = imag(zztarg);
            elseif (strcmp(app.imre, 'Absolute Value'))
                zztarg_plot = abs(zztarg);
            else
                if(strcmpi(app.clmparams.mode,'te'))
                  zztarg_plot_test = (abs(zztarg_grad(1,:)).^2 + ...
                       abs(zztarg_grad(2,:)).^2)*app.clmparams.lambda^2/4/pi^2./abs(nr_region).^2 + ... 
                       abs(zztarg(:).').^2;
                else
                    
                
                    zztarg_plot_test = (abs(zztarg_grad(1,:)).^2 + ...
                       abs(zztarg_grad(2,:)).^2)*app.clmparams.lambda^2/4/pi^2 + ... 
                       abs(zztarg(:).').^2.*abs(nr_region).^2;
                end
                     
                zztarg_plot = reshape(zztarg_plot_test,size(app.xxtarg));
                disp(size(zztarg(:).'))
                disp(size(zztarg_grad(1,:)))
                
            end
            zztarg_test = zztarg_plot(:);
            nnind = false(app.ntarg,1);
            nnind(app.ntid) = 1;
            zztarg_test2 = zztarg_test(~isnan(zztarg_test) & nnind);
            minu = max(min(zztarg_test2(:)));
            maxu = min(max(zztarg_test2(:)));
            
            
            disp(size(zztarg_plot))
            disp(size(zztarg_test))
            f_handle = pcolor(uiax,app.xxtarg,app.yytarg,zztarg_plot);
            set(f_handle,'EdgeColor','none')
            
            if (nargin < 2)
            app.f_handle = f_handle;
            end
            colormap(uiax);
            if (numel(minu)>0 && numel(maxu))
                 caxis(uiax,[minu,maxu]);
            end
            %set(gcf,'Visible','off')
            if (nargin < 2)
                app.f_cb = colorbar(uiax);
            end
            axis(uiax,app.xylim);
            
            hold(uiax,'on')
            
            if (nargin <2)
                delete(app.hs);
                plot_geom(app);
            else
                plot_geom(app,uiax);
            end
            drawnow;
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: KnobDirection
        function KnobDirectionValueChanged(app, event)
            value = app.KnobDirection.Value;
            dir = (value - 50)*30/20;
            app.direction = dir;
            if (dir <-30)
                app.KnobDirection.Value = 30;
                app.direction = -30;
            end
            if (dir >30)
                app.KnobDirection.Value = 70;
                app.direction = 30;
            end
            if(~app.is_mat_current)
                disp('here in knob');
                update_matrix(app);
                app.is_mat_current = true;
            end
            update_rhs(app);
            update_uinc(app);
            update_sol(app);
            update_uscat(app);
            make_plot(app);
        end

        % Value changed function: WavenumberSlider
        function WavenumberSliderValueChanged(app, event)
            value = app.WavenumberSlider.Value;
            if (value == 0)
                app.WavenumberSlider.Value = 0.01;
            end
            value = app.WavenumberSlider.Value;
            value;
            if(abs(app.wavenumber - value/1000)>1e-16)
                disp('here in wavenumber changed')
                app.is_mat_current = false;
                app.wavenumber = value/1000;
                update_geom(app);
                update_rhs(app);
                update_uinc(app);
                update_matrix(app);
                app.is_mat_current = true;
                update_sol(app);
                update_uscat(app);
                make_plot(app);
            end
            
            
        end

        % Value changed function: SelectRegionDropDown
        function SelectRegionDropDownValueChanged(app, event)
            if (app.nregions > 0)
                delete(app.poly_handle);
                if(~isempty(app.f_handle))
                    delete(app.f_handle)
                    delete(app.f_cb);
                end
                

                value = app.SelectRegionDropDown.Value;
                app.RegionLabel.Text = value;
                str_lab = value;
                str_lab = erase(str_lab,"Region #");
                rnum = str2num(str_lab);
                app.R1_Re.Value = app.region_params(1,rnum);
                app.R1_Im.Value = app.region_params(2,rnum);
                app.rcurr = rnum;
                app.btn_plt_type.SelectedObject = app.btn_geom;
                app.plot_option = app.btn_geom.Text;
                app.btn_plt_app.SelectedObject = app.btn_real;
                app.imre = app.btn_real.Text;
                clparams = app.clmparams;
                [xs,xdom] = clm.get_region_pts_gui_hannah2(app.chnk_array,clparams,rnum);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
%%%%%%%%%%%%%%%%%% Delete ME
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
   
                if (rnum == 1)
                    xs(:,end) = [0;Inf];
                end
                if (rnum == 2)
                    xs(:,end) = [0;-Inf];
                end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
%%%%%%%%%%%%%%%%%% END Delete ME
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                
                xs = real(xs);
                
                %app.usr_poly = images.roi.Polygon;
                %app.usr_poly.Parent = app.UIAxes;
                %app.usr_poly.Position = xs';
                if (xs(2,end) ~= Inf && xs(2,end) ~= -Inf)
                    app.usr_poly = polyshape(xs(1,:),xs(2,:));
                    app.poly_handle = plot(app.UIAxes,app.usr_poly);
                    
                elseif (xs(2,end) == Inf)
                    xlim = app.UIAxes.XLim;
                    xdia = xlim(2)-xlim(1);
                    ylim = app.UIAxes.YLim;
                    ydia = ylim(2)-ylim(1);
                    
                    x0 = xlim(2)+xdia/10;
                    y0 = 0;
                    x1 = x0;
                    y1 = ylim(2)+ydia/10;
                    x2 = xlim(1)-xdia/10;
                    y2 = y1;
                    x3 = x2;
                    y3 = 0;
                    xbox = [x0,x1,x2,x3];
                    ybox = [y0,y1,y2,y3];
                    tbox = [xbox;ybox];
                    xs_mod = xs(:,1:(end-1));
                    xs_mod = [tbox(:,3:4),xs_mod,tbox(:,1:3)];
                    app.usr_poly = polyshape(xs_mod(1,:),xs_mod(2,:));
                    app.poly_handle = plot(app.UIAxes,app.usr_poly);
                    axis(app.UIAxes,app.xylim);
                    %app.UIAxes.XLim = xlim;
                    %app.UIAxes.YLim = ylim;   
                else
                    xlim = app.UIAxes.XLim;
                    xdia = xlim(2)-xlim(1);
                    ylim = app.UIAxes.YLim;
                    ydia = ylim(2)-ylim(1);
                    
                    x0 = xlim(2)+xdia/10;
                    y0 = 0;
                    x1 = x0;
                    y1 = ylim(1)-ydia/10;
                    x2 = xlim(1)-xdia/10;
                    y2 = y1;
                    x3 = x2;
                    y3 = 0;
                    xbox = [x0,x1,x2,x3];
                    ybox = [y0,y1,y2,y3];
                    tbox = [xbox;ybox];
                    xs_mod = xs(:,1:(end-1));
                    xs_mod = [xs_mod,tbox];
                    app.usr_poly = polyshape(xs_mod(1,:),xs_mod(2,:));
                    usr_poly2 = polyshape(xdom(1,:),xdom(2,:));
                    app.usr_poly = subtract(app.usr_poly,usr_poly2);
                    app.poly_handle = plot(app.UIAxes,app.usr_poly);
                    axis(app.UIAxes,app.xylim);
                    %app.UIAxes.XLim = xlim;
                    %app.UIAxes.YLim = ylim;               
                end              
            end
        end

        % Value changed function: R1_Re
        function R1_ReValueChanged(app, event)
            value = app.R1_Re.Value;
            
            if(abs(app.region_params(1,app.rcurr) - value)>1e-16)
                app.region_params(1,app.rcurr)=value;
                app.is_mat_current = false;
                update_geom(app);
                update_rhs(app);
                update_uinc(app);
                make_plot(app);
            end
            
        end

        % Value changed function: R1_Im
        function R1_ImValueChanged(app, event)
            value = app.R1_Im.Value;
            if (value <0)
                value = 0;
                app.R1_Im.Value = value;
            end
            if(abs(app.region_params(2,app.rcurr) - value)>1e-16)
                app.is_mat_current = false;
                app.region_params(2,app.rcurr)=value;
                update_geom(app);
                update_rhs(app);
                update_uinc(app);
                make_plot(app);
                

            end
            
            
%             opts = [];
%             opts.rn = app.region_params;
%             [app.chnk_array,app.clmparams] = clm.get_geom_gui(6,opts);
        end

        % Selection changed function: btn_plt_type
        function btn_plt_typeSelectionChanged(app, event)
            delete(app.poly_handle);
            selectedButton = app.btn_plt_type.SelectedObject;
            app.plot_option = selectedButton.Text;
            disp(app.plot_option)
            make_plot(app);
            
        end

        % Selection changed function: btn_plt_app
        function btn_plt_appSelectionChanged(app, event)
            delete(app.poly_handle)
            selectedButton = app.btn_plt_app.SelectedObject;
            app.imre = selectedButton.Text;
            make_plot(app);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            if (app.nregions >0)
            if(~app.is_mat_current)
                update_matrix(app);
                app.is_mat_current = true;
            end
            update_rhs(app);
            update_uinc(app);
            update_sol(app);
            update_uscat(app);
            make_plot(app);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            filename = 'miniwasp_out.mat';
            nregions = app.nregions;
            save(filename,'nregions');
            direction = app.direction;
            save(filename,'direction','-append');
            wavenumber = app.wavenumber;
            save(filename,'wavenumber','-append');
            region_params = app.region_params;
            save(filename,'region_params','-append');
            chnk_array = app.chnk_array;
            save(filename,'chnk_array','-append');
            chnkrtotal = app.chnkrtotal;
            save(filename,'chnkrtotal','-append');
            clmparams = app.clmparams;
            save(filename,'clmparams','-append');
            M = app.M;
            save(filename,'M','-append');
            RG = app.RG;
            save(filename,'RG','-append');
            sol = app.sol;
            save(filename,'sol','-append');
            targs = app.targs;
            save(filename,'targs','-append');
            xxtarg = app.xxtarg;
            save(filename,'xxtarg','-append');
            yytarg = app.yytarg;
            save(filename,'yytarg','-append');
            ntarg = app.ntarg;
            save(filename,'ntarg','-append');
            uscat = app.uscat;
            save(filename,'uscat','-append');
            ugrad = app.ugrad;
            save(filename,'ugrad','-append');
            uinc = app.uinc;
            save(filename,'uinc','-append');
            uincgrad = app.uincgrad;
            save(filename,'uincgrad','-append');
            u = app.u;
            save(filename,'u','-append');
            xg = app.xg;
            save(filename,'xg','-append');
            yg = app.yg;
            save(filename,'yg','-append');
            ngr = app.ngr;
            save(filename,'ngr','-append');
            targlist = app.targlist;
            save(filename,'targlist','-append');
            targdomain = app.targdomain;
            save(filename,'targdomain','-append');
            xylim = app.xylim;
            save(filename,'xylim','-append');
        end

        % Menu selected function: LoadGeometryMenu
        function LoadGeometryMenuSelected(app, event)
            %[file,path] = uigetfile('*.h5;*.mat', ...
     %'Select a geometry file to load (*.h5 or *.mat)','MultiSelect',"off");
            %fname = [path file];
            %app.geom_class = clm.load_geom_gui(fname);
            
            app.UIFigure.Name = 'Loading geometry and initializing targets';
            app.geom_class = clm.read_geom_clm8();
            app.clmparams = clm.setup_geom(app.geom_class);  
            axis_plt = app.UIAxes;
            cla(axis_plt);
            app.chnk_array = [];
            app.chnk_array = clm.get_geom_clmparams(app.clmparams);
            
            
            app.btn_plt_type.SelectedObject = app.btn_geom;
            app.plot_option = app.btn_geom.Text;
            app.btn_plt_app.SelectedObject = app.btn_real;
            app.imre = app.btn_real.Text;
            
            app.nregions = app.clmparams.ndomain;
            app.region_params = [];
            app.default_ui_name = 'Miniwasp';
            
            
            app.SelectRegionDropDown.Items ={};
            Items = [""];
            for i=1:app.nregions
               Items(i) = strcat("Region #",num2str(i));
            end
            
            app.SelectRegionDropDown.Items =Items;
            app.wavenumber = app.clmparams.lambda;
           
            if (app.nregions > 0)
                app.SelectRegionDropDown.Value = Items(1);
                app.RegionLabel.Visible = true;
                reg_params = zeros(2,app.nregions);
                reg_params(1,:) = real(app.clmparams.rn);
                reg_params(2,:) = imag(app.clmparams.rn);
                app.region_params = reg_params;
                app.R1_Re.Value = reg_params(1,1);
                app.R1_Im.Value = reg_params(2,1);
                app.R1_Re.Editable = true;
                app.R1_Im.Editable = true;
            end
            app.ngr = app.clmparams.ngr;       % field evaluation at ngr^2 points
            xylim=app.clmparams.xylim;  % computational domain
            xtarg = linspace(xylim(1),xylim(2),app.ngr);
            ytarg = linspace(xylim(3),xylim(4),app.ngr);
            app.xylim = xylim;
            plot_geom(app);
            [app.xxtarg,app.yytarg] = meshgrid(xtarg,ytarg);
            app.targs = zeros(2,length(app.xxtarg(:)));
            app.targs(1,:) = app.xxtarg(:);
            app.targs(2,:) = app.yytarg(:);
            [~,ntarg] = size(app.targs); 
%             [xg,yg,targs,ntarg] = clm.targinit(xylim,app.ngr);
%             app.targs = targs;
%             app.xg = xg;
%             app.yg = yg;
            app.ntarg = ntarg;
            app.icase = 6;
            [app.targdomain,tid] = clm.finddomain_gui(app.chnk_array, ...
                  app.clmparams,app.targs);
            app.tid = unique(tid);
            app.ntid = setdiff(1:ntarg,app.tid);
            app.targlist = cell(1,app.nregions);
            for i=1:app.nregions
                app.targlist{i} = find(app.targdomain==i);
            end
            
            update_geom(app);


            app.UIFigure.Name = app.default_ui_name;

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            if (1==0)
            t_between_here = toc(app.uiax_click_t)
            app.uiax_clicked
            if (app.uiax_clicked == 0 || t_between_here>0.5)
                app.uiax_click_t = tic();
                app.uiax_clicked = 1;
                disp('here')
            else
                t_between = toc(app.uiax_click_t)
                app.uiax_clicked = 0;
                fig_new = figure;
                new_axis = copyobj(app.UIAxes,fig_new);
            end
            end
        end

        % Button pushed function: ExpandFigureButton
        function ExpandFigureButtonPushed(app, event)
            disp('here')
            if (~isempty(app.chnk_array))
               disp('there')
               f_new = figure;
               axes = uiaxes(f_new,'Position',[10 10 500 400]);
               axes.DataAspectRatio = [20 20 1];
               plot_geom(app,axes)
               make_plot(app,axes)
            end
        end

        % Value changed function: rhabdomdeptSlider
        function rhabdomdeptSliderValueChanged(app, event)
            value = app.rhabdomdeptSlider.Value;
            app.geom_class = clm.read_geom_clm8();
            app.geom_class.verts(2,5:8) = app.geom_class.verts(2,5:8) - value; 
            app.clmparams = clm.setup_geom(app.geom_class);
            app.chnk_array = [];
            app.chnk_array = clm.get_geom_clmparams(app.clmparams);
            
            [~,ntarg] = size(app.targs);
            [app.targdomain,tid] = clm.finddomain_gui(app.chnk_array, ...
                  app.clmparams,app.targs);
            app.tid = unique(tid);
            app.ntid = setdiff(1:ntarg,app.tid);
            app.targlist = cell(1,app.nregions);
            for i=1:app.nregions
                app.targlist{i} = find(app.targdomain==i);
            end
            
            update_geom(app);
            
            %eps = 0.5e-5;
            %opts = [];
            %[app.eva_mats,app.sk_targ] = clm.get_evamat_postproc_im(app.chnk_array,app.clmparams,app.targs, ...
            %    app.targdomain,app.sk,eps,opts);

            
            app.is_mat_current = false;
            if(~isempty(app.hs))
                delete(app.hs);   
            end
            
            if(~isempty(app.poly_handle))
               delete(app.poly_handle);
            end
            
            if(~isempty(app.f_handle))
                delete(app.f_handle);
                delete(app.f_cb);
            end
            plot_geom(app);
            
            update_matrix(app);
            app.is_mat_current = true;
            
            update_rhs(app);
            update_uinc(app);
            update_sol(app);
            update_uscat(app);
            make_plot(app);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadGeometryMenu = uimenu(app.Menu);
            app.LoadGeometryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadGeometryMenuSelected, true);
            app.LoadGeometryMenu.Text = 'Load Geometry';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-15 5];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Panel_4 = uipanel(app.UIFigure);
            app.SolveButton = uibutton(app.Panel_4, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.SaveButton = uibutton(app.Panel_4, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Panel_2 = uipanel(app.UIFigure);
            app.KnobDirection = uiknob(app.Panel_2, 'continuous');
            app.KnobDirection.MajorTicks = [30 40 50 60 70];
            app.KnobDirection.MajorTickLabels = {'-30', '-15', '0', '15', '30'};
            app.KnobDirection.ValueChangedFcn = createCallbackFcn(app, @KnobDirectionValueChanged, true);
            app.KnobDirection.MinorTicks = [30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70];
            app.KnobDirection.Value = 50;
            app.IncidentDirectionLabel = uilabel(app.Panel_2);
            app.IncidentDirectionLabel.Text = 'Incident Direction';
            app.Panel = uipanel(app.UIFigure);
            app.R1_Im = uieditfield(app.Panel, 'numeric');
            app.R1_Im.ValueChangedFcn = createCallbackFcn(app, @R1_ImValueChanged, true);
            app.R1_Im.Editable = 'off';
            app.SelectRegionDropDownLabel = uilabel(app.Panel);
            app.SelectRegionDropDownLabel.Text = 'Select Region';
            app.SelectRegionDropDown = uidropdown(app.Panel);
            app.SelectRegionDropDown.Items = {};
            app.SelectRegionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectRegionDropDownValueChanged, true);
            app.SelectRegionDropDown.Value = {};
            app.RegionLabel = uilabel(app.Panel);
            app.RegionLabel.Visible = 'off';
            app.RegionLabel.Text = 'Region #1';
            app.RealLabel = uilabel(app.Panel);
            app.RealLabel.Text = 'Real';
            app.ImaginaryLabel = uilabel(app.Panel);
            app.ImaginaryLabel.Text = 'Imaginary';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.R1_Re = uieditfield(app.Panel, 'numeric');
            app.R1_Re.ValueChangedFcn = createCallbackFcn(app, @R1_ReValueChanged, true);
            app.R1_Re.Editable = 'off';
            app.IndicesofRefractionLabel = uilabel(app.UIFigure);
            app.IndicesofRefractionLabel.Text = 'Indices of Refraction';
            app.PlotSettingsLabel = uilabel(app.UIFigure);
            app.PlotSettingsLabel.Text = 'Plot Settings:';
            app.btn_plt_type = uibuttongroup(app.UIFigure);
            app.btn_plt_type.SelectionChangedFcn = createCallbackFcn(app, @btn_plt_typeSelectionChanged, true);
            app.btn_geom = uiradiobutton(app.btn_plt_type);
            app.btn_geom.Text = 'Geometry';
            app.btn_geom.Value = true;
            app.btn_inc = uiradiobutton(app.btn_plt_type);
            app.btn_inc.Text = 'Incident Field';
            app.btn_sca = uiradiobutton(app.btn_plt_type);
            app.btn_sca.Text = 'Scattered Field';
            app.btn_tot = uiradiobutton(app.btn_plt_type);
            app.btn_tot.Text = 'Total Field';
            app.btn_plt_app = uibuttongroup(app.UIFigure);
            app.btn_plt_app.SelectionChangedFcn = createCallbackFcn(app, @btn_plt_appSelectionChanged, true);
            app.btn_real = uiradiobutton(app.btn_plt_app);
            app.btn_real.Text = 'Real Part';
            app.btn_real.Value = true;
            app.btn_ima = uiradiobutton(app.btn_plt_app);
            app.btn_ima.Text = 'Imaginary Part';
            app.btn_abs = uiradiobutton(app.btn_plt_app);
            app.btn_abs.Text = 'Absolute Value';
            app.btn_energy = uiradiobutton(app.btn_plt_app);
            app.btn_energy.Text = 'Energy';
            app.wavenumber_panel = uipanel(app.UIFigure);
            app.WavelengthnmLabel = uilabel(app.wavenumber_panel);
            app.WavelengthnmLabel.Text = 'Wavelength (nm)';
            app.WavenumberSlider = uislider(app.wavenumber_panel);
            app.WavenumberSlider.Limits = [380 650];
            app.WavenumberSlider.MajorTicks = [380 515 650];
            app.WavenumberSlider.MajorTickLabels = {'380', '515', '650'};
            app.WavenumberSlider.ValueChangedFcn = createCallbackFcn(app, @WavenumberSliderValueChanged, true);
            app.WavenumberSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80];
            app.WavenumberSlider.Value = 380;
            app.spectrum = uiimage(app.wavenumber_panel);
            app.spectrum.ImageSource = 'visible-light-spectrum.png';
            app.ExpandFigureButton = uibutton(app.UIFigure, 'push');
            app.ExpandFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandFigureButtonPushed, true);
            app.ExpandFigureButton.Text = 'Expand Figure';
            app.rhabdomdeptSliderLabel = uilabel(app.UIFigure);
            app.rhabdomdeptSliderLabel.Text = 'rhabdom dept   ';
            app.Panel_5 = uipanel(app.UIFigure);
            app.RhabdomdepthLabel = uilabel(app.Panel_5);
            app.RhabdomdepthLabel.Text = 'Rhabdom depth';
            app.rhabdomdeptSlider = uislider(app.UIFigure);
            app.rhabdomdeptSlider.Limits = [0 10];
            app.rhabdomdeptSlider.ValueChangedFcn = createCallbackFcn(app, @rhabdomdeptSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hannah2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end